classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        TabGroup   matlab.ui.container.TabGroup
        Tab        matlab.ui.container.Tab
        UITable2   matlab.ui.control.Table
        Button_3   matlab.ui.control.Button
        Button_8   matlab.ui.control.Button
        Button_9   matlab.ui.control.Button
        Button_12  matlab.ui.control.Button
        Tab_2      matlab.ui.container.Tab
        UITable    matlab.ui.control.Table
        Button     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Tab_3      matlab.ui.container.Tab
        UITable3   matlab.ui.control.Table
        Button_4   matlab.ui.control.Button
        Label      matlab.ui.control.Label
        EditField  matlab.ui.control.NumericEditField
        Button_5   matlab.ui.control.Button
        Button_6   matlab.ui.control.Button
        Button_7   matlab.ui.control.Button
        Button_10  matlab.ui.control.Button
        Button_11  matlab.ui.control.Button
        TextArea   matlab.ui.control.TextArea
        UIAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cno % 水泥号
        cvmin %最小速度
        cvmax %最大速度
    end
    
    methods (Access = private)
        
        function upSelectTable(app)
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;
            N=size(Data,1);
            if iBegin+9<=N
                iEnd=iBegin+9;
            else
                iEnd=N;
            end
            Data3=[];
            for i=1:(iEnd-iBegin)+1
                Data3(i,1)=iBegin+i-1;
                Data3(i,2)=Data{iBegin+i-1,5};
            end
            app.UITable3.Data=Data3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function loadDataInfo(app, event)
            %载入数据信息
%             load('a2');
%             data=a2;
            load('allData');
            data=allData;
            N=size(data,1);
            Data=table('Size',[N,10],'VariableTypes',{'double','double','string','double','double','double','double','double','string','string'});
            Data(:,2)=data(:,4); %桩长
            Data(:,3)=data(:,5); %水泥号
            Data(:,4)=data(:,6); %时间间隔
            Data(:,9)=data(:,2); %路径           
            Data(:,10)=data(:,3); %桩号
            Data(:,11)=data(:,1); %工地
            Data(:,1)=table((1:N)'); %序号
            app.UITable2.Data=Data;
            
            if isempty(app.cno)
                loadcVtableData(app,event);
                plotcVtable(app,event);
            end
            
        end

        % Button pushed function: Button_9
        function dataPreprocess(app, event)
            %波形数据前处理
            Data=app.UITable2.Data;
            f=waitbar(0,'Processing your data');
            pause(0.01);
            NN=size(Data,1);
            for i=1:size(Data,1)
                if mod(i,100)==0
                   waitbar(i/NN,f,'Processing your data');
                   pause(0.01);
                end
%                 if i==25370
%                     i
         
                filename=Data{i,9}{1};
                filename(end-2:end)='mat';
                filename=strcat('汇总数据\',filename);
                tmp=exist(filename,'file');
                if tmp==0
                    %未找到文件
                    Data{i,5}=-2; 
                else
                    pileLength=Data{i,2};
                    dt=Data{i,4};% 
                    cNo=Data{i,3};
                    index=app.cno==cNo;
                    vmin=app.cvmin(index);
                    vmax=app.cvmax(index);                        
                    spa=singlePileAnalysis(filename,pileLength,dt,vmin,vmax,cNo);
                    if spa.Ecode==0
                    Data{i,6}=spa.tTop;
                    Data{i,7}=spa.tBottom;
                    Data{i,8}=spa.bottomAmplitude;
                    elseif spa.Ecode==-1
                        %没有找到桩底信号                            
                        Data{i,5}=-1;
                    end                        
                end
%              end
            end
            close(f)
            app.UITable2.Data=Data;
        end

        % Button pushed function: Button_4
        function manuJump2Data(app, event)
            %手动跳转到数据表格并显示图像
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;
            N=size(Data,1);
            
            if iBegin>N
                f = app.UIFigure;
                uialert(f,'超出数据范围','错误开始序号');
            else
                upSelectTable(app)
                
                if iBegin+9>N %一次显示10条记录
                    iEnd=N;
                else
                    iEnd=iBegin+9;
                end
                ax=app.UIAxes;
                ax.NextPlot='replace';
                N=iEnd-iBegin+1;
                
                ytl{1}='';
                tmin=inf;
                tmax=-inf;
                for i=iEnd:-1:iBegin
                    filename=Data{i,9}{1};
                    filename(end-2:end)='mat';
                    filename=strcat('汇总数据\',filename);
                    tmp=exist(filename,'file');
                    if tmp==0
                        %未找到文件
                    else
                        pileLength=Data{i,2};
                        dt=Data{i,4};% 
                        cNo=Data{i,3};
                        index=app.cno==cNo;
                        vmin=app.cvmin(index);
                        vmax=app.cvmax(index);
                        spa=singlePileAnalysis(filename,pileLength,dt,vmin,vmax,cNo);
                        plotShift(spa,(iEnd-i)*2,ax);
                        if tmin>spa.tTop/1000
                            tmin=spa.tTop/1000;
                        end
                        if tmax<spa.tBottom/1000
                            tmax=spa.tBottom/1000;
                        end
                    end
                    ytl{end+1}=strcat('序号',num2str(i));
                    ytl{end+1}='';


                end
                tmin=tmin-1;
                tmax=tmax+1;
                if tmin<0
                        tmin=0;
                end
                ax.YLim=[-1 2*N-1];
                ax.YTick=[-1:1:2*N-1];
                ax.YTickLabel=ytl;
                ax.XLim=[tmin tmax];    
            end
        end

        % Button pushed function: Button
        function loadcVtableData(app, event)
            %载入水泥桩波速数据
            load('cVtable');
            app.UITable.Data=cVtable(:,1:3);    
            app.cno=categorical(cVtable{:,1});
            app.cvmin=cVtable{:,2};
            app.cvmax=cVtable{:,3};
        end

        % Button pushed function: Button_2
        function plotcVtable(app, event)
            %绘制水泥桩数据
            cVtable=app.UITable.Data;
            ax=app.UIAxes;
            ax.NextPlot='replace';
            for i=1:size(cVtable,1)
               plot(ax,[cVtable{i,2},cVtable{i,3}],[i i]);
               ax.NextPlot='add';               
            end
            ax.YTick=[1:size(cVtable,1)];
            ax.YTickLabel=cVtable{:,1};
        end

        % Button pushed function: Button_7
        function lookforward4unlabeled(app, event)
            %向后查找未标注数据
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;
            N=size(Data,1);
            nofind=1;
            while iBegin<N & nofind
                
                if Data{iBegin,5}==0
                    nofind=0;%找到了
                    
                else
                    iBegin=iBegin+1;
                end
                
            end
            app.EditField.Value=iBegin;
            manuJump2Data(app, event)
        end

        % Button pushed function: Button_5
        function markAll1(app, event)
            %全部标1类
            Data3=app.UITable3.Data;
            Data2=app.UITable2.Data;
            for i=1:size(Data3,1)
                Data3(i,2)=1;
                j=Data3(i,1);
                Data2(j,5)={1};
            end
            app.UITable2.Data=Data2;
            app.UITable3.Data=Data3;
        end

        % Button pushed function: Button_6
        function lookbackward4unlabeled(app, event)
            %向前查找未标注数据
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;
            
            nofind=1;
            while iBegin>1 & nofind
                
                if Data{iBegin,5}==0
                    nofind=0;%找到了
                    
                else
                    iBegin=iBegin-1;
                end
                
            end
            app.EditField.Value=iBegin;
            manuJump2Data(app, event)
        end

        % Button pushed function: Button_11
        function lookforward4not1level(app, event)
            %向后查找非1类数据
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;
            N=size(Data,1);
            nofind=1;
            while iBegin<N & nofind                
                if Data{iBegin,5}~=1
                    nofind=0;%找到了                    
                else
                    iBegin=iBegin+1;
                end                
            end
            app.EditField.Value=iBegin;
            manuJump2Data(app, event)
        end

        % Button pushed function: Button_10
        function lookbackward4not1level(app, event)
            %向前查找非1类数据
            iBegin=app.EditField.Value;
            Data=app.UITable2.Data;            
            nofind=1;
            while iBegin>1 & nofind                
                if Data{iBegin,5}~=1
                    nofind=0;%找到了                    
                else
                    iBegin=iBegin-1;
                end                
            end
            app.EditField.Value=iBegin;
            manuJump2Data(app, event)
        end

        % Cell edit callback: UITable3
        function updateTable2(app, event)
            %标注table和数据汇总table联动
            indices = event.Indices;
            newData = event.NewData;
            Data=app.UITable2.Data;
            Data3=app.UITable3.Data;
            Data{Data3(indices(1),1),5}=newData;
            app.UITable2.Data=Data;
        end

        % Button pushed function: Button_8
        function savePrecessData(app, event)
            %保存数据
            data=app.UITable2.Data;
            t=data;
%             t=table(data);
            t.Properties.VariableNames=app.UITable2.ColumnName;
            writetable(t,'PrecessData.xlsx');
        end

        % Button pushed function: Button_12
        function loadPreprossdata(app, event)
            %载入前处理数据
            [filename,filedir]=uigetfile("*.xlsx");
            filename=fullfile(filedir,filename);
            t=readtable(filename);
            app.UITable2.Data=t;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '波形数据';
            app.UITable2 = uitable(app.Tab);
            app.UITable2.ColumnName = {'序号'; '桩长'; '水泥号'; '采样周期'; '类型'; '桩顶时间'; '桩底时间'; '桩底相对振幅'; '路径'; '桩号'; '工地'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true false false false true false false false false true true];
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @loadDataInfo, true);
            app.Button_3.Text = '载入数据';
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @savePrecessData, true);
            app.Button_8.Text = '保存数据';
            app.Button_9 = uibutton(app.Tab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @dataPreprocess, true);
            app.Button_9.Text = '数据前处理';
            app.Button_12 = uibutton(app.Tab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @loadPreprossdata, true);
            app.Button_12.Text = '载入前处理数据';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '水泥桩波速';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'水泥号'; '最小波速'; '最大波速'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @loadcVtableData, true);
            app.Button.Text = '载入数据';
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @plotcVtable, true);
            app.Button_2.Text = '绘制数据';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '标注数据';
            app.UITable3 = uitable(app.Tab_3);
            app.UITable3.ColumnName = {'序号'; '类型'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = true;
            app.UITable3.ColumnEditable = [false true];
            app.UITable3.CellEditCallback = createCallbackFcn(app, @updateTable2, true);
            app.Button_4 = uibutton(app.Tab_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @manuJump2Data, true);
            app.Button_4.Text = '手动跳转';
            app.Label = uilabel(app.Tab_3);
            app.Label.Text = '开始序号';
            app.EditField = uieditfield(app.Tab_3, 'numeric');
            app.EditField.Value = 1;
            app.Button_5 = uibutton(app.Tab_3, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @markAll1, true);
            app.Button_5.Text = '全部标一类';
            app.Button_6 = uibutton(app.Tab_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @lookbackward4unlabeled, true);
            app.Button_6.Text = '向前查找未标注';
            app.Button_7 = uibutton(app.Tab_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @lookforward4unlabeled, true);
            app.Button_7.Text = '向后查找未标注';
            app.Button_10 = uibutton(app.Tab_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @lookbackward4not1level, true);
            app.Button_10.Text = '向前查找非一类';
            app.Button_11 = uibutton(app.Tab_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @lookforward4not1level, true);
            app.Button_11.Text = '向后查找非一类';
            app.TextArea = uitextarea(app.Tab_3);
            app.TextArea.Value = {'-2 没有找到文件'; '-1 找不到桩底信号'; '0 未标记'; '1-4 类信号'; '5 无法分类信号'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end